classdef DoAnCuoiKi < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        Input_1EditField     matlab.ui.control.EditField
        BTChuong8Button      matlab.ui.control.Button
        BTChuong9Button      matlab.ui.control.Button
        BTChuong10Button     matlab.ui.control.Button
        BTChuong11_12Button  matlab.ui.control.Button
        RunButton            matlab.ui.control.Button
        UIAxes               matlab.ui.control.UIAxes
        PhuongPhapDropDown   matlab.ui.control.DropDown
        Input_2EditField2    matlab.ui.control.EditField
        Text_Input_2Label    matlab.ui.control.Label
        Text_Input_1Label    matlab.ui.control.Label
        KetQuaEditField      matlab.ui.control.EditField
        PhuEditField         matlab.ui.control.EditField
        PhuLabel             matlab.ui.control.Label
        Image                matlab.ui.control.Image
        Image_2              matlab.ui.control.Image
        TieuDe               matlab.ui.control.Label
        HoTen                matlab.ui.control.Label
        CanhBaoLabel         matlab.ui.control.Label
        Switch               matlab.ui.control.Switch
        Chon_xy_fxDropDown   matlab.ui.control.DropDown
        SaiSoOhDropDown_2    matlab.ui.control.DropDown
        DH_TP_DropDown       matlab.ui.control.DropDown
        CheckBox             matlab.ui.control.CheckBox
    end

    
    methods (Access = private)
        
        function f = GoiHam(app)
            fx = app.Input_1EditField.Value;
            f = str2func(['@(x)', fx]);
        end
        
        
        function x = ChiaDoi(~, f, a, b, epsilon)
            if f(a) * f(b) > 0
                return;
            end
            k = 0;
            while abs(a - b) >= epsilon && k < 500
                c = (a + b) / 2;
                if f(a) * f(c) < 0
                    b = c;
                else
                    a = c;
                end
                k = k + 1;
            end
            x = c;
        end
        
        function x = LapDon(~, f, a, b, epsilon)
            k = 0;
            x = (a + b) / 2;
            
            while(1)
                y = f(x);
                if abs(y - x) < epsilon || k > 500
                    break;
                end
                x = y;
                k = k + 1;
            end
        end
        
        function xi = TiepTuyen(~, f, a, b, epsilon)
            syms x;
            k = 0;
            if(f(a) > 0)
                xi = a;
            else
                xi = b;
            end
            while(1)
                df = diff(f(x));
                df_fx = matlabFunction(df, 'Vars', x);
                z = f(xi) / df_fx(xi);
                y = xi - z;
                k = k + 1;
                if abs(y - xi) < epsilon || k > 500
                    break;
                end
                xi = y;
            end
            xi = y;
        end
        
        function x = DayCung(~, f, a, b, epsilon)
            n = 0;
            if(f(a) > 0)
                c = b; d = a;
            else
                c = a; d = b;
            end
            while(1)
                x = c;
                c = (x * f(d) - d * f(x)) / (f(d) - f(x));
                n = n + 1;
                if abs(x - c) < epsilon
                    break;
                end
            end
            x = c;
        end
        
        function x = Gauss(~, A, b)
            n = length(b);
            
            for k = 1:n-1
                for i = k+1:n
                    lambda = A(i,k) / A(k,k);
                    for j = k+1:n
                        A(i,j) = A(i,j) - lambda * A(k,j);
                    end
                    b(i) = b(i) - lambda * b(k);
                end
            end
            
            x = zeros(n, 1);
            for i = n:-1:1
                x(i) = (b(i) - A(i,i+1:n) * x(i+1:n)) / A(i,i);
            end
        end
        
        
        function x = Gauss_Jordan(~, A, b)
            n = length(b);
            
            % T?o ma tr?n m? r?ng
            augmented_matrix = [A, b];
            
            % Th?c hi?n Gauss-Jordan
            for k = 1:n
                % Chia dòng k cho A(k, k) ?? ??a ph?n t? chính v? giá tr? 1
                augmented_matrix(k, :) = augmented_matrix(k, :) / augmented_matrix(k, k);
                
                % Lo?i b? các ph?n t? không ph?i trên ???ng chéo
                for i = 1:n
                    if i ~= k
                        augmented_matrix(i, :) = augmented_matrix(i, :) - augmented_matrix(i, k) * augmented_matrix(k, :);
                    end
                end
            end
            
            % Trích xu?t vector k?t qu? x t? c?t cu?i cùng c?a ma tr?n m? r?ng
            x = augmented_matrix(:, end);
            
        end


        function x = Gauss_Partial_Pivoting(~, A, b)
            n = length(b);
            
            % Tạo ma trận mở rộng
            augmented_matrix = [A, b];
            
            for k = 1:n-1
                % Tìm phần tử lớn nhất trong cột k
                [~, max_row] = max(abs(augmented_matrix(k:n, k)));
                max_row = max_row + k - 1;
                
                % Đổi chỗ hàng hiện tại với hàng có phần tử lớn nhất
                augmented_matrix([k, max_row], :) = augmented_matrix([max_row, k], :);
                
                % Loại bỏ các phần tử không phải trên đường chéo
                for i = k+1:n
                    lambda = augmented_matrix(i, k) / augmented_matrix(k, k);
                    augmented_matrix(i, :) = augmented_matrix(i, :) - lambda * augmented_matrix(k, :);
                end
            end
            
            % Trích xuất vector kết quả x từ cột cuối cùng của ma trận mở rộng
            x = augmented_matrix(:, end);
        end
        
        function x = LUsolve(~, A, b)
            % LU decomposition of matrix A and solve L*U*x = b
            % USAGE: x = LUsolve(A, b)
            
            n = size(A, 1);
            
            % LU decomposition
            for k = 1:n-1
                for i = k+1:n
                    if A(i, k) ~= 0.0
                        lambda = A(i, k) / A(k, k);
                        A(i, k+1:n) = A(i, k+1:n) - lambda * A(k, k+1:n);
                        A(i, k) = lambda;
                    end
                end
            end
            
            % Solve L*Y = b using forward substitution
            Y = zeros(n, 1);
            Y(1) = b(1);
            for k = 2:n
                Y(k) = b(k) - A(k, 1:k-1) * Y(1:k-1);
            end
            
            % Solve U*x = Y using backward substitution
            x = zeros(n, 1);
            x(n) = Y(n) / A(n, n);
            for k = n-1:-1:1
                x(k) = (Y(k) - A(k, k+1:n) * x(k+1:n)) / A(k, k);
            end
        end
        
        
        function x = Cholesky(app, A, b)
            % Kiểm tra xem ma trận A có là ma trận vuông và đối xứng không
            [m, n] = size(A);
            if m ~= n || ~isequal(A, A')
                app.KetQuaEditField.Value = 'A phải là ma trận vuông và đối xứng';
                return;
            end
            
            % Kiểm tra xem ma trận A có là ma trận xác định dương không
            if any(eig(A) <= 0)
                app.KetQuaEditField.Value = 'A phải là ma trận xác định dương';
                return;
            end
            
            % Phương pháp Cholesky decomposition
            L = chol(A, 'lower');
            
            % Giải hệ phương trình Ly = b bằng phương pháp substitution tiến
            y = forward_substitution(app, L, b);
            
            % Giải hệ phương trình L'x = y bằng phương pháp substitution lùi
            x = backward_substitution(app, L', y);
        end
        
        function x = forward_substitution(~, L, b)
            % Hàm thực hiện phương pháp substitution tiến
            n = length(b);
            x = zeros(n, 1);
            
            for i = 1:n
                x(i) = (b(i) - L(i, 1:i-1) * x(1:i-1)) / L(i, i);
            end
        end
        
        function x = backward_substitution(~, U, b)
            % Hàm thực hiện phương pháp substitution lùi
            n = length(b);
            x = zeros(n, 1);
            
            for i = n:-1:1
                x(i) = (b(i) - U(i, i+1:n) * x(i+1:n)) / U(i, i);
            end
        end
        
        
        function x = Jacobi(~, A, b, tol, maxIter)
            % Kích thước của ma trận A
            [n, ~] = size(A);
            
            % Khởi tạo vector x ban đầu
            x = zeros(n, 1);
            
            % Khởi tạo vector kết quả tạm thời
            xTemp = zeros(n, 1);
            
            % Lặp qua số lần được định trước hoặc đến khi đạt được sai số mong muốn
            for iter = 1:maxIter
                for i = 1:n
                    sigma = A(i, 1:i-1) * x(1:i-1) + A(i, i+1:end) * x(i+1:end);
                    xTemp(i) = (b(i) - sigma) / A(i, i);
                end
                
                % Tính sai số tuyệt đối
                error = norm(xTemp - x, inf);
                
                % Cập nhật vector x
                x = xTemp;
                
                % Kiểm tra điều kiện dừng
                if error < tol
                    return;
                end
            end
            
        end


        function X = gaussSeidel(~, A, B, tol, maxIter)
            n = length(B);
            X = zeros(n, 1);
            numIter = 0;
            
            for k = 1:maxIter
                XTemp = X;
                for i = 1:n
                    X(i) = (B(i) - A(i, [1:i-1, i+1:end]) * X([1:i-1, i+1:end])) / A(i, i);
                end
                
                err = norm(X - XTemp, inf);
                
                numIter = numIter + 1;
                
                if err < tol
                    return;
                end
            end
        end
        
        
        function [yx, Pn] = Lagrange(~, x, y)
            u = sym('u');
            
            n = length(x);
            Pn = sym(0);
            
            for i = 1:n
                term = y(i);
                for j = 1:n
                    if j ~= i
                        term = term * (u - x(j)) / (x(i) - x(j));
                    end
                end
                Pn = Pn + term;
            end
            yx = matlabFunction(Pn);
            Pn = simplify(Pn);
            
        end
        
        function [yx, Pn] = NewtonInterpolation(~, x, y)
            N = length(x) - 1;
            DD = zeros(N + 1, N + 1);
            DD(1:N + 1, 1) = y';
            
            for k = 2:N + 1
                for m = 1:N + 2 - k
                    DD(m, k) = (DD(m + 1, k - 1) - DD(m, k - 1)) / (x(m + k - 1) - x(m));
                end
            end
            
            a = DD(1, :);
            n = a(N + 1);
            
            for k = N:-1:1
                n = [n a(k)] - [0 n * x(k)];
            end
            
            u = sym('u');
            Pn = a(N + 1);
            for k = N:-1:1
                Pn = Pn * (u - x(k)) + a(k);
            end
            
            yx = matlabFunction(Pn);
            
            Pn = simplify(Pn);
            Pn = expand(Pn);
            Pn = vpa(Pn, 5);
        end
        
        function [fx, Pn] = HoiQuyBac1(~, x1, y)
            n = length(x1);
            a = (n * sum(x1.*y) - sum(x1) * sum(y)) / (n * sum(x1.^2) - sum(x1) * sum(x1));
            b = mean(y) - a * mean(x1);
            
            
            syms x;
            fx = matlabFunction(a * x + b);
            
            Pn = a * x + b;
            Pn = vpa(Pn, 4);
        end
        
        
        function [fx, Pn] = HoiQuyBac2(~, x1, y)
            n = length(x1);
            A = [sum(x1.^4) sum(x1.^3) sum(x1.^2);...
                sum(x1.^3) sum(x1.^2) sum(x1);
                sum(x1.^2) sum(x1) n];
            
            B = [sum(y.*x1.^2); sum(y.*x1); sum(y)];
            
            heso = A\B;
            a = heso(1);
            b = heso(2);
            c = heso(3);
            
            
            syms x;
            fx = matlabFunction(a*x^2 + b*x + c);
            
            Pn = a*x^2 + b*x + c;
            
            Pn = vpa(Pn, 4);
        end
        
        function [fx, Pn] = HoiQuyHamMu(~, x1, y)
            x1 = log(x1);
            Y = log(y);
            
            n = length(x1);
            A = (n*sum(x1.*Y) - sum(x1)*sum(Y))/(n*sum(x1.^2) - sum(x1)*sum(x1));
            B = mean(Y) - A*mean(x1);
            
            a = exp(B);
            b = A;
            
            
            syms x;
            fx = matlabFunction(a*x^b);
            b1 = round(b, 4);
            Pn = a*x^b1;
            Pn = vpa(Pn, 4);
        end
        
        function [fx, Pn] = HoiQuyLogarit(~, x1, y)
            n = length(x1);
            x1_log = log(x1);
            a = (n * sum(x1_log.*y) - sum(x1_log) * sum(y)) / (n * sum(x1_log.^2) - sum(x1_log) * sum(x1_log));
            b = mean(y) - a * mean(x1_log);
            
            % Tạo hàm fx = a*log(x) + b
            syms x;
            fx = matlabFunction(a * log(x) + b);
            
            % Tạo symbolic expression Pn
            Pn = a * log(x) + b;
            
            % Làm tròn số thập phân của Pn (nếu cần)
            Pn = vpa(Pn, 4);
        end
        
        function df = taylor_xapxitien(app, x, y, xi, Oh)
            h = x(2) - x(1);
            
            if xi < x(1) || xi >= x(end)
                
                df = '';
                return;
            end
            
            i = find(x <= xi, 1, 'last');
            
            if i < length(x) && Oh == 1
                df = (y(i+1) - y(i)) / h;
            elseif i < length(x)-1 && Oh == 2
                df = (-y(i+2) + 4*y(i+1) - 3*y(i)) / (2*h);
            else
                app.KQ_EditField_5.Value = 'Không đủ dữ liệu';
                return;
            end
        end


        function df = taylor_xapxilui(~, x, y, xi, Oh)
            h = x(2) - x(1);
            
            if xi <= x(1) || xi > x(end)
                
                df = '';
                return;
            end
            
            i = find(x >= xi, 1, 'first');
            
            if i > 1 && Oh == 1
                df = (y(i) - y(i-1)) / h;
            elseif i > 2 && Oh == 2
                df = (y(i-2) - 4*y(i-1) + 3*y(i)) / (2*h);
            else
                
                return;
            end
        end
        
        function df = taylor_xapxitrungtam(~, x, y, xi, Oh)
            h = x(2) - x(1);
            
            if xi < x(1) || xi > x(end)
                
                df = '';
                return;
            end
            
            i = find(x <= xi, 1, 'last');
            k = find(x >= xi, 1, 'first');
            
            if i < length(x) && k > 1 && Oh == 2
                df = (y(i+1) - y(i-1)) / (2 * h);
            elseif i < length(x)-1 && k > 2 && Oh == 1
                df = (-y(i+2) + 8*y(i+1) - 8*y(i-1) + y(i-2)) / (12*h);
            else
                
                df = 'l';
                return;
            end
        end
        
        function df = taylor_fx_xapxitien(~, f, xi, h, Oh)
            yx = str2sym(f);
            fx = matlabFunction(yx);
            
            if Oh == 1
                df = (fx(xi + h) - fx(xi)) /  h;
            elseif Oh == 2
                df = (-fx(xi + 2*h) + 4*fx(xi + h) - 3*fx(xi)) / (2 * h);
            end
        end
        
        function df = taylor_fx_xapxilui(~, f, xi, h, Oh)
            
            yx = str2sym(f);
            fx = matlabFunction(yx);
            
            if Oh == 1
                df = (fx(xi) - fx(xi - h)) / h;
            elseif Oh == 2
                df = (fx(xi - 2*h) - 4*fx(xi - h) + 3*fx(xi)) / (2 * h);
            end
        end
        
        function df = taylor_fx_xapxitrungtam(~, f, xi, h, Oh)
            
            yx = str2sym(f);
            fx = matlabFunction(yx);
            
            if Oh == 2
                df = (fx(xi + h) - fx(xi - h)) / (2 * h);
            elseif Oh == 1
                df = (-fx(xi + 2*h) + 8*fx(xi + h) - 8*fx(xi - h) + fx(xi - 2*h)) / (12 * h);
            end
        end
        
        function df = DaoHamRicharson(~, f, h, xi)
            yx = str2sym(f);
            fx = matlabFunction(yx);
            d1 = (fx(xi + h) - fx(xi - h)) / (2 * h);
            d2 = (fx(xi + h/2) - fx(xi - h/2)) / (h);
            df = 4/3*d2 - 1/3*d1;
        end
        
        function df = DaoHamRicharson_xy(~, x, y, xi)
            h = x(2) - x(1);
            h = h*2;
            if xi <= x(2) || xi >= x(end-1)
                df = '';
                return;
            end
            i = find(x <= xi, 1, 'last');
            
            d1 = (y(i + 2) - y(i - 2)) / (2 * h);
            d2 = (y(i + 1) - y(i - 1)) / (h);
            df = 4/3*d2 - 1/3*d1;
        end
        
        
        function y = TichPhanHinhThang(~, fx, a, b, N)
            if isempty(a) || isempty(b) || isempty(fx) || a>b
                y = '';
                return;
            end
            h = (b - a) / N;
            fa = fx(a);
            fb = fx(b);
            sum_inside = 0;
            for i = 1:N-1
                xi = a + i * h;
                sum_inside = sum_inside + fx(xi);
            end
            y = (h / 2) * (fa + 2 * sum_inside + fb);
        end
        
        
        function result = TichPhanHinhThang_xy(~, x, y, a, b)
            if isempty(a) || isempty(b) || isempty(x) || isempty(y) || length(x) ~= length(y)
                result = '';
                return;
            end
            if a < x(1) || b > x(end) || a>b
                result = 'Error';
                return;
            end
            index_a = find(x >= a, 1);
            index_b = find(x <= b, 1, 'last');
            if isempty(index_a) || isempty(index_b) || index_a > index_b
                result = 'Error';
                return;
            end
            x_selected = x(index_a:index_b);
            y_selected = y(index_a:index_b);
            N_selected = length(x_selected) - 1;
            h = (x_selected(end) - x_selected(1)) / N_selected;
            fa = y_selected(1);
            fb = y_selected(end);
            sum_inside = sum(y_selected(2:end-1));
            result = (h / 2) * (fa + 2 * sum_inside + fb);
        end
        
        
        function y = TichPhanSimpson13(~, fx, a, b, N)
            if a > b || isempty(a) || isempty(b) || isempty(fx)
                y = '';
                return;
            end
            h = (b - a) / N;
            fa = fx(a);
            fb = fx(b);
            sum_odd = 0;
            sum_even = 0;
            for i = 1:N-1
                xi = a + i * h;
                if mod(i, 2) == 1
                    sum_odd = sum_odd + fx(xi);
                else
                    sum_even = sum_even + fx(xi);
                end
            end
            y = (h / 3) * (fa + 4 * sum_odd + 2 * sum_even + fb);
        end
        
        
        function result = TichPhanSimpson13_xy(~, x, y, a, b)
            if isempty(a) || isempty(b) || isempty(x) || isempty(y) || length(x) ~= length(y)
                result = '';
                return;
            end
            if a < x(1) || b > x(end) || a>b
                result = 'Error';
                return;
            end
            index_a = find(x >= a, 1);
            index_b = find(x <= b, 1, 'last');
            if isempty(index_a) || isempty(index_b) || index_a > index_b
                result = 'Error';
                return;
            end
            x_selected = x(index_a:index_b);
            y_selected = y(index_a:index_b);
            N = length(x_selected) - 1;
            h = (x_selected(end) - x_selected(1)) / N;
            fa = y_selected(1);
            fb = y_selected(end);
            sum_odd = sum(y_selected(2:2:end-1));
            sum_even = sum(y_selected(3:2:end-2));
            result = (h / 3) * (fa + 4 * sum_odd + 2 * sum_even + fb);
        end
        
        
        function y = TichPhanSimpson38(~, fx, a, b, N)
            if isempty(a) || isempty(b) || isempty(fx) || a>b
                y = '';
                return;
            end
            h = (b - a) / N;
            fa = fx(a);
            fb = fx(b);
            sum_1 = 0;
            sum_2 = 0;
            sum_3 = 0;
            for i = 1:N-1
                xi = a + i * h;
                if mod(i, 3) == 1
                    sum_1 = sum_1 + fx(xi);
                elseif mod(i, 3) == 2
                    sum_2 = sum_2 + fx(xi);
                else
                    sum_3 = sum_3 + fx(xi);
                end
            end
            y = (3 * h / 8) * (fa + 3 * sum_1 + 3 * sum_2 + 2 * sum_3 + fb);
        end
        
        
        function result = TichPhanSimpson38_xy(~, x, y, a, b)
            if isempty(a) || isempty(b) || isempty(x) || isempty(y) || length(x) ~= length(y)
                result = '';
                return;
            end
            if a < x(1) || b > x(end) || a>b
                result = 'Error';
                return;
            end
            index_a = find(x >= a, 1);
            index_b = find(x <= b, 1, 'last');
            if isempty(index_a) || isempty(index_b) || index_a > index_b
                result = 'Error';
                return;
            end
            x_selected = x(index_a:index_b);
            y_selected = y(index_a:index_b);
            N = length(x_selected) - 1;
            h = (x_selected(end) - x_selected(1)) / N;
            fa = y_selected(1);
            fb = y_selected(end);
            sum_1 = sum(y_selected(2:3:end-1));
            sum_2 = sum(y_selected(3:3:end-2));
            sum_3 = sum(y_selected(4:3:end-3));
            result = (3 * h / 8) * (fa + 3 * sum_1 + 3 * sum_2 + 2 * sum_3 + fb);
        end
        
        
        function y = TichPhanGaussLegendre(~, f, a, b)
            syms x
            sym xd
            a0 = (b+a)/2;
            a1 = (b-a)/2;
            xd = a0 + a1*x;
            fd = matlabFunction(a1.*f(xd));
            y = fd(1/sqrt(3)) + fd(-1/sqrt(3));
        end


        function fxy = viphan_Heun(~, f, t0, tf, y0, h)
            N = (tf - t0)/h;
            t = t0 + (0:N)'*h;
            y(1,:) = y0(:)';
            for k = 1:N
                fk = feval(f, t(k), y(k,:));
                y(k+1,:) = y(k,:) + h*fk;
                y(k+1,:) = y(k,:) + h/2*(fk + feval(f, t(k+1), y(k+1,:)));
            end
            fxy = y(end);
        end
        
        
        function fxy = viphan_TrungDiem(~, fxy, xdau, xcuoi, y0, h)
            N = (xcuoi - xdau)/h;
            x = xdau + (0:N)'*h;
            y = zeros(length(x), 1);
            y(1) = y0;
            
            for i = 1:N
                k1 = h * feval(fxy, x(i), y(i));
                k2 = h * feval(fxy, x(i) + h/2, y(i) + k1/2);
                y(i+1) = y(i) + k2;
            end
            fxy = y(end);
        end
        
        function fxy = viphan_RK3(~, f, t0, tf, y0, h)
            N = (tf - t0) / h;
            t = t0 + (0:N)' * h;
            y(1, :) = y0(:)';
            
            for k = 1:N
                k1 = h * feval(f, t(k), y(k, :));
                k1 = k1(:)';
                k2 = h * feval(f, t(k) + h/2, y(k, :) + k1/2);
                k2 = k2(:)';
                k3 = h * feval(f, t(k) + h, y(k,:) - k1 + 2*k2);
                k3 = k3(:)';
                y(k+1, :) = y(k, :) + (k1 + 4*k2 + k3) / 6;
            end
            fxy = y(end);
        end
        
        
        function fxy = viphan_RK4(~, f, t0, tf, y0, h)
            N = (tf - t0)/h;
            t = t0 + (0:N)'*h;
            y(1,:) = y0(:)';
            for k = 1:N
                k1 = h*feval(f, t(k), y(k,:));
                k1 = k1(:)';
                k2 = h*feval(f, t(k) + h/2, y(k,:) + k1/2);
                k2 = k2(:)';
                k3 = h*feval(f, t(k) + h/2, y(k,:) + k2/2);
                k3 = k3(:)';
                k4 = h*feval(f, t(k) + h, y(k,:) + k3);
                k4 = k4(:)';
                y(k+1,:) = y(k,:) + (k1 + 2*k2 + 2*k3 + k4)/6;
            end
            fxy = y(end);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BTChuong8Button
        function BTChuong8ButtonPushed(app, event)
            app.KetQuaEditField.BackgroundColor = [1, 1, 1];
            cla(app.UIAxes);
            app.CanhBaoLabel.Visible = 'off';
            app.PhuEditField.Visible = 'off';
            app.PhuLabel.Visible = 'off';
            app.Switch.Visible = 'off';
            app.KetQuaEditField.Value = 'Kết quả';
            
            app.BTChuong8Button.BackgroundColor = [0, 1, 0];
            app.BTChuong9Button.BackgroundColor = [1, 1, 1];
            app.BTChuong10Button.BackgroundColor = [1, 1, 1];
            app.BTChuong11_12Button.BackgroundColor = [1, 1, 1];
            
            app.PhuongPhapDropDown.Items = {'Phương Pháp Chia Đôi'};
            app.Input_1EditField.Value = 'x^3 - 2*x^2 - 4*x + 7';
            app.Input_2EditField2.Value = '[2, 3, 0.01]';
            app.Text_Input_2Label.Text = '[a, b, epsilon]';
            app.Text_Input_1Label.Text = 'Hàm f(x)';
            
            app.PhuongPhapDropDown.Items = {'Phương Pháp Chia Đôi', 'Phương Pháp Lặp Đơn',...
                'Phương Pháp Newton', 'Phương Pháp Dây Cung'};
            
            app.SaiSoOhDropDown_2.Visible = 'off';
            app.KetQuaEditField.Position = [10,113,181,30];
            
            app.DH_TP_DropDown.Visible = 'off';
            app.Chon_xy_fxDropDown.Visible = 'off';
            app.CheckBox.Visible = 'off';
            
        end

        % Value changed function: PhuongPhapDropDown
        function PhuongPhapDropDownValueChanged(app, event)
            value1 = app.PhuongPhapDropDown.Value;
            if strcmp(value1, 'Phương Pháp Lặp Đơn')
                app.Text_Input_1Label.Text = 'Hàm g(x)';
                app.Input_1EditField.Value = '(2*x^2 + 4*x - 7)^(1/3)';
            elseif strcmp(value1, 'Phương Pháp Chia Đôi')
                app.Text_Input_1Label.Text = 'Hàm f(x)';
                app.Input_1EditField.Value = 'x^3 - 2*x^2 - 4*x + 7';
            elseif strcmp(value1, 'Phương Pháp Newton')
                app.Text_Input_1Label.Text = 'Hàm f(x)';
                app.Input_1EditField.Value = 'x^3 - 2*x^2 - 4*x + 7';
            elseif strcmp(value1, 'Phương Pháp Dây Cung')
                app.Text_Input_1Label.Text = 'Hàm f(x)';
                app.Input_1EditField.Value = 'x^3 - 2*x^2 - 4*x + 7';
            end
            if isequal(app.BTChuong9Button.BackgroundColor, [0, 1, 0])
                if strcmp(value1, 'Phương Pháp Lặp Jacobi')
                    app.PhuEditField.Visible = 'on';
                    app.PhuLabel.Visible = 'on';
                    app.PhuLabel.Text = '[Sai số, Số lần lặp tối đa]';
                    app.PhuEditField.Value = '[0.05, 100]';
                elseif strcmp(value1, 'Phương Pháp Lặp Gauss-Seidel')
                    app.PhuEditField.Visible = 'on';
                    app.PhuLabel.Visible = 'on';
                    app.PhuLabel.Text = '[Sai số, Số lần lặp tối đa]';
                    app.PhuEditField.Value = '[0.05, 100]';
                else
                    app.PhuEditField.Visible = 'off';
                    app.PhuLabel.Visible = 'off';
                end
            end
           
%             if isequal(app.BTChuong10Button.BackgroundColor, [0, 1, 0])
%                 if strcmp(value1, 'Nội Suy Newton')
%                     app.Input_2EditField2.Value = '3 5 2 4';
%                 elseif strcmp(value1, 'Nội Suy Lagrange')
%                     app.Input_2EditField2.Value = '2 4 1 6';
%                 end
%             end
            if isequal(app.BTChuong11_12Button.BackgroundColor, [0, 1, 0])
                if strcmp(value1, 'Xấp Xỉ Trung Tâm')
                    app.SaiSoOhDropDown_2.Items = {'Sai số: O(h^2)', 'Sai số: O(h^4)'};
                elseif strcmp(value1, 'Ngoại Suy Richardson')
                    app.SaiSoOhDropDown_2.Items = {'Sai số: O(h^4)'};
                else
                    app.SaiSoOhDropDown_2.Items = {'Sai số: O(h)', 'Sai số: O(h^2)'};
                end
            end
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            
            if isequal(app.BTChuong8Button.BackgroundColor, [0, 1, 0])
                fx = app.Input_1EditField.Value;
                f = str2func(['@(x)', fx]);
                %ham = str2func(['@(t)', fx]);
                So = str2num(app.Input_2EditField2.Value);
                a = So(1,1);
                b = So(1,2);
                epsilon = So(1,3);
                cla(app.UIAxes);
                grid(app.UIAxes, 'on');
                t = (a-20):1/10000:(b+20);
                y = arrayfun(f, t);
                plot(app.UIAxes, t, y, 'r', 'LineWidth', 2);
                hold(app.UIAxes, 'on');
                plot(app.UIAxes, [0,0], [-10*b,10*b], 'k', 'LineWidth', 0.5);
                plot(app.UIAxes, [-10*b,10*b], [0,0], 'k', 'LineWidth', 0.5);
                axis(app.UIAxes, [a-5,b+5,-2*b,2*b]);
                
                value1 = app.PhuongPhapDropDown.Value;
                if strcmp(value1, 'Phương Pháp Lặp Đơn')
                    y1 = t;
                    plot(app.UIAxes, t, y1, 'blue', 'LineWidth', 1);
                end
                scatter(app.UIAxes, a, 0, 20, 'k', 'filled');
                scatter(app.UIAxes, b, 0, 20, 'k', 'filled');
                GoiHam(app);
                
                switch app.PhuongPhapDropDown.Value
                    case 'Phương Pháp Chia Đôi'
                        ngh = ChiaDoi(app, f, a, b, epsilon);
                    case 'Phương Pháp Lặp Đơn'
                        ngh = LapDon(app, f, a, b, epsilon);
                    case 'Phương Pháp Newton'
                        ngh = TiepTuyen(app, f, a, b, epsilon);
                    otherwise %Dây cung
                        ngh = DayCung(app, f, a, b, epsilon);
                end
                app.KetQuaEditField.Value = num2str(ngh);
            elseif isequal(app.BTChuong9Button.BackgroundColor, [0, 1, 0])
                cla(app.UIAxes);
                A = app.Input_1EditField.Value;
                A = str2num(A);
                b = app.Input_2EditField2.Value;
                b = str2num(b);
                c = app.PhuEditField.Value;
                c = str2num(c);
                switch app.PhuongPhapDropDown.Value
                    case 'Phương Pháp Gauss'
                        x1 = Gauss(app, A, b);
                    case 'Phương Pháp Gauss-Jordan'
                        x1 = Gauss_Jordan(app, A, b);
                    case 'Phương Pháp Gauss-Trụ Tối Đại'
                        x1 = Gauss_Partial_Pivoting(app, A, b);
                    case 'Phương Pháp Phân Tích LU'
                        x1 = LUsolve(app, A, b);
                    case 'Phương Pháp Cholesky'
                        x1 = Cholesky(app, A, b);
                    case 'Phương Pháp Lặp Jacobi'
                        x1 = Jacobi(app, A, b, c(1), c(2));
                    otherwise
                        x1 = gaussSeidel(app, A, b, c(1), c(2));
                end %end của switch tìm nghiệm
                x_rounded = round(x1, 2);
                result_matrix_str = ['[', sprintf('%.2f; ', x_rounded(1:end-1)), sprintf('%.2f', x_rounded(end)), ']'];
                
                app.KetQuaEditField.Value = result_matrix_str;
                
            elseif isequal(app.BTChuong10Button.BackgroundColor, [0, 1, 0])
                x = app.Input_1EditField.Value;
                x = str2num(x);
                y = app.Input_2EditField2.Value;
                y = str2num(y);
                
                if strcmp(app.Switch.Value, 'Nội Suy')
                    if (size(x) == size(y))
                        app.CanhBaoLabel.Visible = 'off';
                        switch app.PhuongPhapDropDown.Value
                            case 'Nội Suy Lagrange'
                                [yx, ~] = Lagrange(app, x, y);
                            case 'Nội Suy Newton'
                                [yx, ~] = NewtonInterpolation(app, x, y);
                        end
                        ns = app.PhuEditField.Value;
                        ns = str2num(ns);
                        if isnumeric(ns)
                            app.KetQuaEditField.Value = num2str(yx(ns));
                        end
                        cla(app.UIAxes);
                        app.UIAxes.reset;
                        grid(app.UIAxes, 'on');
                        t = linspace(min(x), max(x), 100000);
                        Pn_values = yx(t);
                        
                        plot(app.UIAxes, t, Pn_values, 'LineWidth', 2);
                        hold(app.UIAxes, 'on');
                        scatter(app.UIAxes, x, y, 50, 'red', 'filled');
                    else
                        app.KetQuaEditField.Value = '';
                        cla(app.UIAxes);
                        app.CanhBaoLabel.Visible = 'on';
                    end
                    
                else
                    if (size(x) == size(y))
                        app.CanhBaoLabel.Visible = 'off';
                        switch app.PhuongPhapDropDown.Value
                            case 'Hồi Quy Bậc 1'
                                [fx, ~] = HoiQuyBac1(app, x, y);
                            case 'Hồi Quy Bậc 2'
                                [fx, ~] = HoiQuyBac2(app, x, y);
                            case 'Hồi Quy Hàm Mũ'
                                [fx, ~] = HoiQuyHamMu(app, x, y);
                            case 'Hồi Quy Logarit'
                                [fx, ~] = HoiQuyLogarit(app, x, y);
                        end
                        ns = app.PhuEditField.Value;
                        ns = str2num(ns);
                        if isnumeric(ns)
                            app.KetQuaEditField.Value = num2str(fx(ns));
                        end
                        cla(app.UIAxes);
                        t = linspace(min(x), max(x), 100000);
                        Pn_values = fx(t);
                        plot(app.UIAxes, t, Pn_values, 'LineWidth', 2);
                        hold(app.UIAxes, 'on');
                        scatter(app.UIAxes, x, y, 50, 'red', 'filled');
                    else
                        app.KetQuaEditField.Value = '';
                        cla(app.UIAxes);
                        app.CanhBaoLabel.Visible = 'on';
                    end
                end
                
            elseif isequal(app.BTChuong11_12Button.BackgroundColor, [0, 1, 0])
                if strcmp(app.DH_TP_DropDown.Value, 'Đạo Hàm')
                    if strcmp(app.SaiSoOhDropDown_2.Value, 'Sai số: O(h^2)')
                        Oh = 2;
                    else
                        Oh = 1;
                    end
                    xi = app.PhuEditField.Value;
                    xi = str2num(xi);
                    dfx = 'l';
                    switch app.Chon_xy_fxDropDown.Value
                        case 'Nhập dữ liệu x'
                            x1 = app.Input_1EditField.Value;
                            x1 = str2num(x1);
                            y = app.Input_2EditField2.Value;
                            y = str2num(y);
                            
                            if strcmp(app.PhuongPhapDropDown.Value, 'Xấp Xỉ Tiến')
                                df = taylor_xapxitien(app, x1, y, xi, Oh);
                            elseif strcmp(app.PhuongPhapDropDown.Value, 'Xấp Xỉ Lùi')
                                df = taylor_xapxilui(app, x1, y, xi, Oh);
                            elseif strcmp(app.PhuongPhapDropDown.Value, 'Xấp Xỉ Trung Tâm')
                                df = taylor_xapxitrungtam(app, x1, y, xi, Oh);
                            elseif strcmp(app.PhuongPhapDropDown.Value, 'Ngoại Suy Richardson')
                                df = DaoHamRicharson_xy(app, x1, y, xi);
                            end
                        case 'Nhập hàm số f(x)'
                            %GoiHam(app);
                            h = app.Input_2EditField2.Value;
                            h = str2num(h);
                            f = app.Input_1EditField.Value;
                            
                            if strcmp(app.PhuongPhapDropDown.Value, 'Xấp Xỉ Tiến')
                                df = taylor_fx_xapxitien(app, f, xi, h, Oh);
                            elseif strcmp(app.PhuongPhapDropDown.Value, 'Xấp Xỉ Lùi')
                                df = taylor_fx_xapxilui(app, f, xi, h, Oh);
                            elseif strcmp(app.PhuongPhapDropDown.Value, 'Xấp Xỉ Trung Tâm')
                                df = taylor_fx_xapxitrungtam(app, f, xi, h, Oh);
                            elseif strcmp(app.PhuongPhapDropDown.Value, 'Ngoại Suy Richardson')
                                df = DaoHamRicharson(app, f, h, xi);
                            end
                    end
                    
                    if isnumeric(df)
                        kq = num2str(df);
                        app.KetQuaEditField.Value = num2str(kq);
                    end
                    
                elseif strcmp(app.DH_TP_DropDown.Value, 'Tích Phân')
                    switch app.Chon_xy_fxDropDown.Value
                        case 'Nhập dữ liệu x'
                            x1 = str2num(app.Input_1EditField.Value);
                            y1 = str2num(app.Input_2EditField2.Value);
                            a = str2num(app.PhuEditField.Value);
                            app.UIAxes.cla;
                            switch app.PhuongPhapDropDown.Value
                                case 'Tích Phân Hình Thang'
                                    y = TichPhanHinhThang_xy(app, x1, y1, a(1), a(2));
                                case 'Simpson 1/3'
                                    y = TichPhanSimpson13_xy(app, x1, y1, a(1), a(2));
                                case 'Simpson 3/8'
                                    y = TichPhanSimpson38_xy(app, x1, y1, a(1), a(2));
                                otherwise
                                    y = '';
                            end
                        otherwise
                            N = str2num(app.Input_2EditField2.Value);
                            f = app.Input_1EditField.Value;
                            fx = str2func(['@(x)', f]);
                            a = str2num(app.PhuEditField.Value);
                            app.UIAxes.cla;
                            app.UIAxes.reset;
                            switch app.PhuongPhapDropDown.Value
                                case 'Tích Phân Hình Thang'
                                    y = TichPhanHinhThang(app, fx, a(1), a(2), N);
                                case 'Simpson 1/3'
                                    if mod(N, 2) == 1
                                        N = N + 1;
                                    end
                                    y = TichPhanSimpson13(app, fx, a(1), a(2), N);
                                case 'Simpson 3/8'
                                    if mod(N, 3) == 1
                                        N = N + 2;
                                    elseif mod(N, 3) == 2
                                        N = N + 1;
                                    end
                                    y = TichPhanSimpson38(app, fx, a(1), a(2), N);
                                otherwise
                                    y = TichPhanGaussLegendre(app, fx, a(1), a(2));
                            end
                            % Vẽ đồ thị
                            x_vals = linspace(a(1), a(2), 10000);
                            u = linspace(a(1)-100, a(2)+100, 10000);
                            y_vals = fx(x_vals);
                            f = fx(u);
                            
                            % Tạo hoặc cập nhật đồ thị trong App Designer
                            figure(app.UIFigure);
                            plot(app.UIAxes, u, f, 'LineWidth', 1.5, 'Color', 'k');
                            
                            hold(app.UIAxes, 'on');
                            
                            % Tô đậm phần diện tích giữa hàm và trục Ox trên toàn bộ khoảng
                            fill(app.UIAxes, [a(1), x_vals, a(2)], [0, y_vals, 0], 'k', 'FaceAlpha', 0.1);
                            scatter(app.UIAxes, 0, 0, 20, 'k', 'filled');
                            plot(app.UIAxes, [0,0], [-10000,10000], 'k', 'LineWidth', 0.5);
                            plot(app.UIAxes, [a(1)-1000, a(2)+1000], [0,0], 'k', 'LineWidth', 0.5);
                            % Đặt giới hạn cho trục x và trục y
                            if a(1) >= 0
                                k1 = -2;
                            else
                                k1 = a(1) - 1;
                            end
                            
                            if a(2) <= 0
                                k2 = 2;
                            else
                                k2 = a(2) + 1;
                            end
                            
                            if min(y_vals) >= 0
                                k3 = -2;
                            else
                                k3 = min(y_vals)-4;
                            end
                            
                            if max(y_vals) <= 0
                                k4 = 2;
                            else
                                k4 = max(y_vals)+4;
                            end
                            axis(app.UIAxes, [k1 k2 k3 k4]);
                            hold(app.UIAxes, 'off');
                    end
                    
                    app.KetQuaEditField.Value = num2str(y);
                    
                elseif strcmp(app.DH_TP_DropDown.Value, 'Vi Phân')
                    
                    temp = str2num(app.Input_2EditField2.Value);
                    f = app.Input_1EditField.Value;
                    fx = str2func(['@(x,y)', f]);
                    tf = str2num(app.PhuEditField.Value);
                    
                    switch app.PhuongPhapDropDown.Value
                        case 'Vi Phân Heun'
                            vp = viphan_Heun(app, fx, temp(1), tf, temp(2), temp(3));
                        case 'Vi Phân Euler cải tiến'
                            vp = viphan_TrungDiem(app, fx, temp(1), tf, temp(2), temp(3));
                        case 'Vi Phân Runge Kutta 3'
                            vp = viphan_RK3(app, fx, temp(1), tf, temp(2), temp(3));
                        case 'Vi Phân Runge Kutta 4'
                            vp = viphan_RK4(app, fx, temp(1), tf, temp(2), temp(3));
                    end
                    app.KetQuaEditField.Value = num2str(vp);
                end %end DH, TP, VP
            end %end của if
            app.KetQuaEditField.BackgroundColor = [rand(1, 1), rand(1, 1), rand(1, 1)];
        end

        % Button pushed function: BTChuong9Button
        function BTChuong9ButtonPushed(app, event)
            app.KetQuaEditField.BackgroundColor = [1, 1, 1];
            cla(app.UIAxes);
            app.CanhBaoLabel.Visible = 'off';
            app.PhuEditField.Visible = 'off';
            app.PhuLabel.Visible = 'off';
            app.Switch.Visible = 'off';
            app.KetQuaEditField.Value = 'Kết quả';
            
            app.BTChuong9Button.BackgroundColor = [0, 1, 0];
            app.BTChuong8Button.BackgroundColor = [1, 1, 1];
            app.BTChuong10Button.BackgroundColor = [1, 1, 1];
            app.BTChuong11_12Button.BackgroundColor = [1, 1, 1];
            
            app.PhuongPhapDropDown.Items = {'Phương Pháp Gauss', 'Phương Pháp Gauss-Jordan',...
                'Phương Pháp Gauss-Trụ Tối Đại', 'Phương Pháp Phân Tích LU', 'Phương Pháp Cholesky',...
                'Phương Pháp Lặp Jacobi', 'Phương Pháp Lặp Gauss-Seidel'};
            app.PhuongPhapDropDown.Value = 'Phương Pháp Cholesky';
            app.Input_1EditField.Value = '[4.9 1.0 0.1 1.1; 1.0 6.4 1.2 0.2; 0.1 1.2 3.6 1.1; 1.1 0.2 1.1 6.4]';
            app.Input_2EditField2.Value = '[5.0; 2.2; 3.7; 2.2]';
            app.Text_Input_2Label.Text = 'Matrix B [ ]';
            app.Text_Input_1Label.Text = 'Matrix A [ ]';
            
            app.SaiSoOhDropDown_2.Visible = 'off';
            app.KetQuaEditField.Position = [10,113,181,30];
            
            app.DH_TP_DropDown.Visible = 'on';
            app.DH_TP_DropDown.Items = {'4.3 Câu 3', '4.3 Câu 4'};
            app.Chon_xy_fxDropDown.Visible = 'off';
            app.CheckBox.Visible = 'off';
            
        end

        % Button pushed function: BTChuong10Button
        function BTChuong10ButtonPushed(app, event)
            app.KetQuaEditField.BackgroundColor = [1, 1, 1];
            cla(app.UIAxes);
            app.BTChuong10Button.BackgroundColor = [0, 1, 0];
            app.BTChuong9Button.BackgroundColor = [1, 1, 1];
            app.BTChuong8Button.BackgroundColor = [1, 1, 1];
            app.BTChuong11_12Button.BackgroundColor = [1, 1, 1];
            
            app.KetQuaEditField.Value = 'Kết quả';
            app.Input_1EditField.Value = '321 322.8 324.2 325';
            app.Input_2EditField2.Value = '2.50651 2.50893 2.51081 2.51188';
            
            app.PhuongPhapDropDown.Items = {'Nội Suy Lagrange', 'Nội Suy Newton'};
            
            app.PhuLabel.Text = 'Nhập giá trị nội suy';
            app.PhuLabel.Visible = 'on';
            app.PhuEditField.Value = '323.5';
            app.PhuEditField.Visible ='on';
            
            app.Text_Input_2Label.Text = 'Nhập dữ liệu y';
            app.Text_Input_1Label.Text = 'Nhập dữ liệu x';
            
            app.Switch.Visible = 'on';
            app.Switch.Value = 'Nội Suy';
            
            app.SaiSoOhDropDown_2.Visible = 'off';
            app.KetQuaEditField.Position = [10,113,181,30];
            
            app.DH_TP_DropDown.Visible = 'off';
            app.Chon_xy_fxDropDown.Visible = 'off';
            app.CheckBox.Visible = 'off';
            
        end

        % Button pushed function: BTChuong11_12Button
        function BTChuong11_12ButtonPushed(app, event)
            app.KetQuaEditField.BackgroundColor = [1, 1, 1];
            cla(app.UIAxes);
            app.CanhBaoLabel.Visible = 'off';
            app.PhuEditField.Visible = 'off';
            app.PhuLabel.Visible = 'off';
            app.Switch.Visible = 'off';
            app.KetQuaEditField.Value = 'Kết quả';
            
            app.BTChuong11_12Button.BackgroundColor = [0, 1, 0];
            app.BTChuong9Button.BackgroundColor = [1, 1, 1];
            app.BTChuong10Button.BackgroundColor = [1, 1, 1];
            app.BTChuong8Button.BackgroundColor = [1, 1, 1];
            
            app.DH_TP_DropDown.Visible = 'on';
            app.DH_TP_DropDown.Items = {'Đạo Hàm', 'Tích Phân', 'Vi Phân'};
            app.DH_TP_DropDown.Value = 'Đạo Hàm';
            
            if strcmp(app.DH_TP_DropDown.Value, 'Đạo Hàm')
                app.SaiSoOhDropDown_2.Visible = 'on';
                app.KetQuaEditField.Position = [10,58,181,30];
                app.Text_Input_2Label.Text = 'Nhập dữ liệu y';
                app.Text_Input_1Label.Text = 'Nhập dữ liệu x';
            end
            app.PhuongPhapDropDown.Items = {'Xấp Xỉ Tiến', 'Xấp Xỉ Lùi', 'Xấp Xỉ Trung Tâm',...
                'Ngoại Suy Richardson'};
            app.PhuongPhapDropDown.Value = 'Xấp Xỉ Tiến';
            app.SaiSoOhDropDown_2.Items = {'Sai số: O(h)', 'Sai số: O(h^2)'};
            
            
            app.Chon_xy_fxDropDown.Visible = 'on';
            app.Chon_xy_fxDropDown.Items = {'Nhập dữ liệu x', 'Nhập hàm số f(x)'};
            app.Chon_xy_fxDropDown.Value = 'Nhập dữ liệu x';
            app.Input_1EditField.Value = '0 0.25 0.5 0.75 1';
            app.Input_2EditField2.Value = '1.2 1.1035 0.925 0.6363 0.2';
            
            app.PhuLabel.Text = 'Nhập giá trị đạo hàm';
            app.PhuLabel.Visible = 'on';
            app.PhuEditField.Value = '0.5';
            app.PhuEditField.Visible ='on';
            
            app.CheckBox.Visible = 'off';
            app.CheckBox.Value = 0;
            
            
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            if strcmp(app.Switch.Value, 'Nội Suy')
                app.PhuongPhapDropDown.Items = {'Nội Suy Lagrange', 'Nội Suy Newton'};
                app.Input_1EditField.Value = '321 322.8 324.2 325';
                app.Input_2EditField2.Value = '2.50651 2.50893 2.51081 2.51188';
                app.PhuEditField.Value = '323.5';
                app.PhuLabel.Text = 'Nhập giá trị nội suy';
                app.UIAxes.cla;
            else
                app.PhuongPhapDropDown.Items = {'Hồi Quy Bậc 1', 'Hồi Quy Bậc 2', 'Hồi Quy Hàm Mũ'...
                    'Hồi Quy Logarit'};
                app.Input_1EditField.Value = '1 1 2 2 2 3 3 4 5 6';
                app.Input_2EditField2.Value = '1 2 2 3 4 4 5 5 6 7';
                app.PhuEditField.Value = '1.26';
                app.PhuLabel.Text = 'Nhập giá trị hồi quy';
                app.UIAxes.cla;
                app.UIAxes.reset;
                grid(app.UIAxes, 'on');
            end
            
        end

        % Value changed function: DH_TP_DropDown
        function DH_TP_DropDownValueChanged(app, event)
            if strcmp(app.DH_TP_DropDown.Value, 'Đạo Hàm')
                app.SaiSoOhDropDown_2.Visible = 'on';
                app.Chon_xy_fxDropDown.Visible = 'on';
                app.KetQuaEditField.Position = [10,58,181,30];
                app.Text_Input_2Label.Text = 'Nhập dữ liệu y';
                app.Chon_xy_fxDropDown.Value = 'Nhập dữ liệu x';
                app.PhuongPhapDropDown.Items = {'Xấp Xỉ Tiến', 'Xấp Xỉ Lùi', 'Xấp Xỉ Trung Tâm',...
                    'Ngoại Suy Richardson'};
                
                app.Input_1EditField.Value = '0 0.25 0.5 0.75 1';
                app.Input_2EditField2.Value = '1.2 1.1035 0.925 0.6363 0.2';
                app.PhuLabel.Text = 'Nhập giá trị đạo hàm';
                app.PhuEditField.Value = '0.5';
                app.CheckBox.Visible = 'off';
                app.CheckBox.Value = 0;
                app.UIAxes.cla;
                
            elseif strcmp(app.DH_TP_DropDown.Value, 'Tích Phân')
                app.SaiSoOhDropDown_2.Visible = 'off';
                app.Chon_xy_fxDropDown.Visible = 'on';
                app.KetQuaEditField.Position = [10,114,181,30];
                app.Text_Input_2Label.Text = 'Nhập dữ liệu y';
                app.Chon_xy_fxDropDown.Value = 'Nhập dữ liệu x';
                app.PhuongPhapDropDown.Items = {'Tích Phân Hình Thang', 'Simpson 1/3', 'Simpson 3/8',...
                    'Gauss Legendre'};
                
                app.Input_1EditField.Value = '1 2 3 4 5';
                app.Input_2EditField2.Value = '1 1/2 1/3 1/4 1/5';
                app.PhuLabel.Text = 'Nhập cận tích phân';
                app.PhuEditField.Value = '1 5';
                app.CheckBox.Visible = 'off';
                app.CheckBox.Value = 0;
                
            elseif strcmp(app.DH_TP_DropDown.Value, 'Vi Phân')
                app.SaiSoOhDropDown_2.Visible = 'off';
                app.KetQuaEditField.Position = [10,114,181,30];
                app.Text_Input_1Label.Text = 'Nhập hàm vi phân f(x,y)';
                app.Text_Input_2Label.Text = 'Nhập x0, y(x0), bước nhảy h';
                app.Chon_xy_fxDropDown.Visible = 'off';
                app.PhuongPhapDropDown.Items = {'Vi Phân Heun', 'Vi Phân Euler cải tiến',...
                    'Vi Phân Runge Kutta 3', 'Vi Phân Runge Kutta 4'};
                
                app.Input_1EditField.Value = '1 + x^2 + y';
                app.Input_2EditField2.Value = '0, 1, 0.1';
                app.PhuLabel.Text = 'Nhập giá trị vi phân';
                app.PhuEditField.Value = '0.5';
                app.CheckBox.Visible = 'off';
                app.CheckBox.Value = 0;
                app.UIAxes.cla;
                
            elseif strcmp(app.DH_TP_DropDown.Value, '4.3 Câu 4')
                app.Input_1EditField.Value = '[7.7 1.2 1.1 2.1; 1.2 8.7 1.3 1.1; 1.1 1.3 8.3 1.0; 2.1 1.1 1.0 4.9]';
                app.Input_2EditField2.Value = '[9.8; 2.3; 2.1; 7.0]';
            elseif strcmp(app.DH_TP_DropDown.Value, '4.3 Câu 3')
                app.Input_1EditField.Value = '[4.9 1.0 0.1 1.1; 1.0 6.4 1.2 0.2; 0.1 1.2 3.6 1.1; 1.1 0.2 1.1 6.4]';
                app.Input_2EditField2.Value = '[5.0; 2.2; 3.7; 2.2]';
            end


        end

        % Value changed function: Chon_xy_fxDropDown
        function Chon_xy_fxDropDownValueChanged(app, event)
            if strcmp(app.Chon_xy_fxDropDown.Value, 'Nhập dữ liệu x')
                app.CheckBox.Visible = 'off';
                app.CheckBox.Value = 0;
                app.Text_Input_2Label.Text = 'Nhập dữ liệu y';
                if strcmp(app.DH_TP_DropDown.Value, 'Đạo Hàm')
                    app.Input_1EditField.Value = '0 0.25 0.5 0.75 1';
                    app.Input_2EditField2.Value = '1.2 1.1035 0.925 0.6363 0.2';
                    app.PhuLabel.Text = 'Nhập giá trị đạo hàm';
                    app.PhuEditField.Value = '0.5';
                else
                    app.Input_1EditField.Value = '1 2 3 4 5';
                    app.Input_2EditField2.Value = '1 1/2 1/3 1/4 1/5';
                    app.PhuLabel.Text = 'Nhập cận tích phân';
                    app.PhuEditField.Value = '1 5';
                end
            else
                app.CheckBox.Visible = 'on';
                if strcmp(app.DH_TP_DropDown.Value, 'Đạo Hàm')
                    app.Text_Input_2Label.Text = 'Nhập bước nhảy h';
                    app.Input_1EditField.Value = '-0.1*x^4 - 0.15*x^3 - 0.5*x^2 - 0.25*x + 1.2';
                    app.Input_2EditField2.Value = '0.25';
                    app.CheckBox.Text = 'Kết quả đạo hàm chính xác:';
                    app.CheckBox.Position = [10,27,228,22];
                else
                    app.CheckBox.Text = 'Kết quả tích phân chính xác:';
                    app.Text_Input_2Label.Text = 'Nhập số lần lặp N';
                    app.Input_1EditField.Value = 'x.^3 + 2.*x - 15';
                    app.Input_2EditField2.Value = '9';
                    app.CheckBox.Position = [10,80,228,22];
                end
            end
            
        end

        % Value changed function: CheckBox
        function CheckBoxValueChanged(app, event)
            if strcmp(app.DH_TP_DropDown.Value, 'Đạo Hàm')
                if app.CheckBox.Value
                    f = app.Input_1EditField.Value;
                    xi_str = app.PhuEditField.Value;
                    xi = str2num(xi_str); % Chuyển đổi thành số
                    fx = str2sym(f);
                    syms x;
                    dfx = diff(fx, x);
                    dfx_func = matlabFunction(dfx);
                    dfxi = dfx_func(xi);
                    app.CheckBox.Text = ['Kết quả đạo hàm chính xác: ' num2str(dfxi)];
                else
                    app.CheckBox.Text = 'Kết quả đạo hàm chính xác:';
                end
            else
                if app.CheckBox.Value
                    f = app.Input_1EditField.Value;
                    if ~isempty(f)
                        fx = str2func(['@(x)', f]);
                    else
                        fx = '';
                    end
                    a = str2num(app.PhuEditField.Value);
                    n1 = quad(f, a(1), a(2));
                    app.CheckBox.Text = ['Kết quả tích phân chính xác: ' num2str(n1)];
                else
                    app.CheckBox.Text = 'Kết quả tích phân chính xác: ';
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Input_1EditField = uieditfield(app.UIFigure, 'text');
            app.Input_1EditField.Value = 'Nhập dữ liệu';
            app.BTChuong8Button = uibutton(app.UIFigure, 'push');
            app.BTChuong8Button.ButtonPushedFcn = createCallbackFcn(app, @BTChuong8ButtonPushed, true);
            app.BTChuong8Button.Text = 'BT Chương 8';
            app.BTChuong9Button = uibutton(app.UIFigure, 'push');
            app.BTChuong9Button.ButtonPushedFcn = createCallbackFcn(app, @BTChuong9ButtonPushed, true);
            app.BTChuong9Button.Text = 'BT Chương 9';
            app.BTChuong10Button = uibutton(app.UIFigure, 'push');
            app.BTChuong10Button.ButtonPushedFcn = createCallbackFcn(app, @BTChuong10ButtonPushed, true);
            app.BTChuong10Button.Text = 'BT Chương 10';
            app.BTChuong11_12Button = uibutton(app.UIFigure, 'push');
            app.BTChuong11_12Button.ButtonPushedFcn = createCallbackFcn(app, @BTChuong11_12ButtonPushed, true);
            app.BTChuong11_12Button.Text = 'BT Chương 11+12';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Đồ thị hàm số')
            xlabel(app.UIAxes, 'x')
            ylabel(app.UIAxes, 'y')
            app.PhuongPhapDropDown = uidropdown(app.UIFigure);
            app.PhuongPhapDropDown.Items = {'Chọn Phương Pháp'};
            app.PhuongPhapDropDown.ValueChangedFcn = createCallbackFcn(app, @PhuongPhapDropDownValueChanged, true);
            app.PhuongPhapDropDown.Value = 'Chọn Phương Pháp';
            app.Input_2EditField2 = uieditfield(app.UIFigure, 'text');
            app.Input_2EditField2.Value = 'Nhập dữ liệu';
            app.Text_Input_2Label = uilabel(app.UIFigure);
            app.Text_Input_2Label.Text = '';
            app.Text_Input_1Label = uilabel(app.UIFigure);
            app.Text_Input_1Label.Text = '';
            app.KetQuaEditField = uieditfield(app.UIFigure, 'text');
            app.KetQuaEditField.Editable = 'off';
            app.KetQuaEditField.Value = 'Kết quả';
            app.PhuEditField = uieditfield(app.UIFigure, 'text');
            app.PhuEditField.Visible = 'off';
            app.PhuLabel = uilabel(app.UIFigure);
            app.PhuLabel.Visible = 'off';
            app.PhuLabel.Text = '';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'IMG_LogoHCMUS.png';
            app.Image_2 = uiimage(app.UIFigure);
            app.Image_2.ImageSource = 'IMG_AVATAR.png';
            app.TieuDe = uilabel(app.UIFigure);
            app.TieuDe.Text = {'BÁO CÁO GIỮA KỲ'; 'Môn học: Phương pháp tính và Matlab'};
            app.HoTen = uilabel(app.UIFigure);
            app.HoTen.Text = {'Lớp: DTV_CLC1'; 'Họ và tên: Đỗ Minh Chương'; 'MSSV: 21207126'};
            app.CanhBaoLabel = uilabel(app.UIFigure);
            app.CanhBaoLabel.Visible = 'off';
            app.CanhBaoLabel.Text = 'Dữ Liệu Không Hợp Lệ';
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.Items = {'Nội Suy', 'Hồi Quy'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Visible = 'off';
            app.Switch.Value = 'Nội Suy';
            app.Chon_xy_fxDropDown = uidropdown(app.UIFigure);
            app.Chon_xy_fxDropDown.Items = {'Nhập dữ liệu x', 'Nhập hàm số f(x)'};
            app.Chon_xy_fxDropDown.ValueChangedFcn = createCallbackFcn(app, @Chon_xy_fxDropDownValueChanged, true);
            app.Chon_xy_fxDropDown.Visible = 'off';
            app.Chon_xy_fxDropDown.Value = 'Nhập hàm số f(x)';
            app.SaiSoOhDropDown_2 = uidropdown(app.UIFigure);
            app.SaiSoOhDropDown_2.Items = {'Chọn Phương Pháp'};
            app.SaiSoOhDropDown_2.Visible = 'off';
            app.SaiSoOhDropDown_2.Value = 'Chọn Phương Pháp';
            app.DH_TP_DropDown = uidropdown(app.UIFigure);
            app.DH_TP_DropDown.Items = {};
            app.DH_TP_DropDown.ValueChangedFcn = createCallbackFcn(app, @DH_TP_DropDownValueChanged, true);
            app.DH_TP_DropDown.Visible = 'off';
            app.DH_TP_DropDown.Value = {};
            app.CheckBox = uicheckbox(app.UIFigure);
            app.CheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CheckBox.Visible = 'off';
            app.CheckBox.Text = 'Kết quả tích phân chính xác: ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DoAnCuoiKi

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end